classdef HelicopterParameterEstimation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HelicopterParameterEstimationUIFigure  matlab.ui.Figure
        AirfoilSelectionDropDown       matlab.ui.control.DropDown
        AirfoilSelectionDropDownLabel  matlab.ui.control.Label
        FuelWeightkgEditField          matlab.ui.control.NumericEditField
        BatteryNumberLabel             matlab.ui.control.Label
        HelpButton                     matlab.ui.control.Button
        TailbladenumberEditField       matlab.ui.control.NumericEditField
        TailbladenumberEditFieldLabel  matlab.ui.control.Label
        CalculateParametersButton      matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        MainbladenumberEditField       matlab.ui.control.NumericEditField
        MainbladenumberEditFieldLabel  matlab.ui.control.Label
        MTOWkgEditField                matlab.ui.control.NumericEditField
        MTOWkgEditFieldLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CalculateParametersButton, UITable
        function CalculateButtonPushed(app, event)
%% Main Rotor
D = 0.977 * app.MTOWkgEditField.Value^(0.308); % Diameter
R = D/2;                    % Radius
c = .0619*app.MTOWkgEditField.Value^(.226)/...
    app.MainbladenumberEditField.Value^(.426); % Chord
AngVel = 57.967*D^(0.6149)/R;

%% Tail Rotor
Dtr = 0.0886 * app.MTOWkgEditField.Value^(0.393);  % Diameter
Rtr = Dtr/2;                % Radius
AngVeltr = 57.967*Dtr^(0.6149)/Rtr;

%% Engine for Take-off Total Power
Pto = 0.2928 * app.MTOWkgEditField.Value^(0.9043); % Total power at GTOW

%% Airframe
Lf = 0.824*D^(1.056);   % Fuselage length
Lrt = 1.09*D^(1.03);    % Overall length

Lfin = Lrt-R;
Afin = (.160*D - 0.148) / (Lfin);

%% Weight Prediction
We = 0.59*app.MTOWkgEditField.Value;
Wb = app.FuelWeightkgEditField.Value;
Wpl = app.MTOWkgEditField.Value - We - Wb;

if Wpl < 0
msgbox("Not feasible due to MTOW and Battery Capacity inputs","Error");
Wpl = "Error";
end

%% Coefficients
% Vertical Tail
Coeffs = struct;
Coeffs.F1 = 1.4*(Afin/1.11);
Coeffs.K1 = 0.77*(Afin/1.11);

% Fuselage
FrontAreaRatio = (28*43)/(102.6*80.13);
SideAreaRatio = (108*43)/(497*122.5);
UpperAreaRatio = (28*108)/(102.6*497);

Coeffs.D1 = 1.05*FrontAreaRatio;
Coeffs.L1 = 3.13*FrontAreaRatio;
Coeffs.Y1 =  .29*SideAreaRatio;
Coeffs.D2 = 9.62*SideAreaRatio;
Coeffs.D3 = 30.92*UpperAreaRatio;
Coeffs.M1 = 13.95*UpperAreaRatio;
Coeffs.N1 = 13.95*SideAreaRatio;

% Airfoil
airfoilselec = app.AirfoilSelectionDropDown.Value;

if airfoilselec == "NACA 0012"
CL_a = 5.5;
end

% lock number
Iblade = R*c*0.04*1600*0.25*R^2;
lockNum = 1.225*CL_a*c*R^4/Iblade;

% twist angles
rootang = 10*pi/180;
twistang = -9*pi/180;

%% Inertias
% Data for helicopters (MTOW, Ixx, Iyy, Izz)
helicopters = [
    4.975, 3.834, 1.315, 1.44;
    1609, 1515, 4863, 3869;    % Bell 407
    7439, 7631, 54232, 50436; % UH-60
    9500, 14707, 61104, 52684; % AS 332 MK II
    68, 1.9887, 6.2051, 5.9751; % Yamaha R-50
    2800, 3795, 17259, 14639   % UH-1H
];

% Extract MTOW and moments of inertia
MTOW = helicopters(:, 1);
Ixx = helicopters(:, 2);
Iyy = helicopters(:, 3);
Izz = helicopters(:, 4);

% Create interpolation functions for Ixx, Iyy, Izz
interp_Ixx = @(x) interp1(MTOW, Ixx, x, 'linear', 'extrap');
interp_Iyy = @(x) interp1(MTOW, Iyy, x, 'linear', 'extrap');
interp_Izz = @(x) interp1(MTOW, Izz, x, 'linear', 'extrap');

% Interpolate for MTOW = 100kg
MTOW_desired = app.MTOWkgEditField.Value;
Ixx_estimated = interp_Ixx(MTOW_desired);
Iyy_estimated = interp_Iyy(MTOW_desired);
Izz_estimated = interp_Izz(MTOW_desired);

Inertias = [Ixx_estimated,Iyy_estimated,Izz_estimated];


%% Table
app.UITable.Data = ...
   {'Maximum Take-off Weight (kg)', sprintf('%.1f', app.MTOWkgEditField.Value);
    'Main Blade Number', sprintf('%.0f', app.MainbladenumberEditField.Value);
    'Tail Blade Number', sprintf('%.0f', app.TailbladenumberEditField.Value);
    'Empty Weight (kg)', sprintf('%.1f', We);
    'Fuel Weight (kg)', sprintf('%.1f', Wb);
    'Payload (kg)', sprintf('%.1f', Wpl);
    'Main Rotor Radius (m)', sprintf('%.2f', R);
    'Main Rotor Chord (m)', sprintf('%.2f', c);
    'Main Rotor Angular Velocity (1/s)', sprintf('%.0f', AngVel);
    'Tail Rotor Radius (m)', sprintf('%.2f', Rtr);
    'Tail Rotor Angular Velocity (1/s)', sprintf('%.0f', AngVeltr);
    'Takeoff Total Power (kW)', sprintf('%.2f', Pto);
    'Fuselage Length (m)', sprintf('%.2f', Lf);
    'Airframe Length (m)', sprintf('%.2f', Lrt);
    %'Electric Motor Selection', sprintf('%s', EngSelec);
    %'Motor Power Sea Level (kW)', sprintf('%.0f', EngPowSL);
    %'Battery Capacity (mAh)', sprintf('%.0f', BatteryCap)
    'Ixx (kg m^2)', sprintf('%.2f', Inertias(1))
    'Iyy (kg m^2)', sprintf('%.2f', Inertias(2))
    'Izz (kg m^2)', sprintf('%.2f', Inertias(3))};

%% ASSIGN-IN
assignin('base', 'MTOW', app.MTOWkgEditField.Value);
assignin('base', 'Nb', app.MainbladenumberEditField.Value);
assignin('base', 'Nbtr', app.TailbladenumberEditField.Value);
assignin('base', 'We', We);
assignin('base', 'Wpl', Wpl);
assignin('base', 'Wb', Wb);
assignin('base', 'R', R);
assignin('base', 'c', c);
assignin('base', 'AngVel', AngVel);
assignin('base', 'Rtr', Rtr);
assignin('base', 'AngVeltr', AngVeltr);
assignin('base', 'Pto', Pto);
assignin('base', 'Lf', Lf);
assignin('base', 'Lrt', Lrt);
assignin('base', 'Afin', Afin);
assignin('base', 'Lfin', Lfin);
assignin('base', 'Coeffs', Coeffs);
assignin('base', 'lockNum', lockNum);
assignin('base', 'CL_a', CL_a);
assignin('base', 'rootang', rootang);
assignin('base', 'twistang', twistang);
assignin('base', 'Inertias', Inertias);

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            
f = uifigure('Name', 'Help', 'Position', [800, 500, 350, 280]);

uilabel(f, 'Position', [20, 20, 320, 250], ...
    'Text', "This application is based on studies that enable a first" + ...
    " design approach to be made with the regression method by" + ...
                " conducting statistical studies on previously" + ...
                " made small scale helicopter designs and correlating some" + ...
                " design parameters with parameters such as weight" + ...
                " and diameter. This application makes it easier to" + ...
                " make some design choices by using the regression" + ...
                " equations contained in these statistical studies. " +...
                "The calculated parameters with this method is not "+...
                "eligible for finding last values. But it is proper for "+...
                "using as preliminary design. Parameters can be further "+...
                "optimized by aerodynamic and structural analysis.",...
               'FontSize', 14, 'FontName', 'Arial',"WordWrap","on",...
               'HorizontalAlignment', "left","VerticalAlignment","top");


f.Visible = 'on';

            % msgbox("This application is based on studies that enable a first" + ...
            %     "design approach to be made with the regression method by" + ...
            %     " conducting statistical studies on previously made" + ...
            %     " helicopter designs and correlating some design " + ...
            %     "parameters with parameters such as weight and diameter." + ...
            %     " This application makes it easier to make some design" + ...
            %     " choices by using the regression equations contained" + ...
            %     " in these statistical studies."+newline+newline+"In this " + ...
            %     "context, the following sources were used." + newline + newline +...
            %     "- DESIGN TRENDS FOR ROTARY-WING UNMANNED AIR VEHICLES - Vladimir Khromov and Omri Rand" + newline +...
            %     "- ROTOR PARAMETERS OF SMALL WEIGHT HELICOPTERS - V. Dudnik et al.", 'Help');
uiresume(app.HelicopterParameterEstimationUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HelicopterParameterEstimationUIFigure = uifigure('Visible', 'off');
            app.HelicopterParameterEstimationUIFigure.Name = 'Helicopter Parameter Estimation';
            app.MTOWkgEditFieldLabel = uilabel(app.HelicopterParameterEstimationUIFigure);
            app.MTOWkgEditFieldLabel.Text = 'MTOW (kg)';
            app.MTOWkgEditField = uieditfield(app.HelicopterParameterEstimationUIFigure, 'numeric');
            app.MTOWkgEditField.Value = 100;
            app.MainbladenumberEditFieldLabel = uilabel(app.HelicopterParameterEstimationUIFigure);
            app.MainbladenumberEditFieldLabel.Text = 'Main blade number';
            app.MainbladenumberEditField = uieditfield(app.HelicopterParameterEstimationUIFigure, 'numeric');
            app.MainbladenumberEditField.Value = 2;
            app.UITable = uitable(app.HelicopterParameterEstimationUIFigure);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateParametersButton = uibutton(app.HelicopterParameterEstimationUIFigure, 'push');
            app.CalculateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateParametersButton.Text = 'Calculate Parameters';
            app.TailbladenumberEditFieldLabel = uilabel(app.HelicopterParameterEstimationUIFigure);
            app.TailbladenumberEditFieldLabel.Text = 'Tail blade number';
            app.TailbladenumberEditField = uieditfield(app.HelicopterParameterEstimationUIFigure, 'numeric');
            app.TailbladenumberEditField.Value = 2;
            app.HelpButton = uibutton(app.HelicopterParameterEstimationUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.BatteryNumberLabel = uilabel(app.HelicopterParameterEstimationUIFigure);
            app.BatteryNumberLabel.Text = 'Fuel Weight (kg)';
            app.FuelWeightkgEditField = uieditfield(app.HelicopterParameterEstimationUIFigure, 'numeric');
            app.FuelWeightkgEditField.Tooltip = {'7.65 kg per unit'};
            app.FuelWeightkgEditField.Value = 30;
            app.AirfoilSelectionDropDownLabel = uilabel(app.HelicopterParameterEstimationUIFigure);
            app.AirfoilSelectionDropDownLabel.Text = 'Airfoil Selection';
            app.AirfoilSelectionDropDown = uidropdown(app.HelicopterParameterEstimationUIFigure);
            app.AirfoilSelectionDropDown.Items = {'NACA 0012', 'NACA 23012'};
            app.AirfoilSelectionDropDown.Value = 'NACA 0012';
            % Show the figure after all components are created
            app.HelicopterParameterEstimationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HelicopterParameterEstimation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HelicopterParameterEstimationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HelicopterParameterEstimationUIFigure)
        end
    end
end